classdef SnakeAppDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel_2  matlab.ui.control.Label
        SNAKECOLORDropDownLabel  matlab.ui.control.Label
        SNAKECOLORDropDown       matlab.ui.control.DropDown
        SCOREEditField_2Label    matlab.ui.control.Label
        SCOREEditField_2         matlab.ui.control.NumericEditField
        NashGloverLabel          matlab.ui.control.Label
        ShreyaGulatiLabel        matlab.ui.control.Label
        PriyankaNambiarLabel     matlab.ui.control.Label
        UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel  matlab.ui.control.Label
        SnakeLabel               matlab.ui.control.Label
        EatthefoodtogrowlongerandincreaseyourscoreLabel  matlab.ui.control.Label
        AvoidhittingthewallsoryourselforthegamewillendLabel  matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        direction = 0; % initialize variable to left direction
        score = 0; % initialize variable of original score to 0 
        SnakeColorValue = '4CCB32'; % initialize default color
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SNAKECOLORDropDown
        function SNAKECOLORDropDownValueChanged(app, event)
            SelectedColor = app.SNAKECOLORDropDown.Value;

            if strcmp(SelectedColor, 'Green')
                app.SnakeColorValue = '#4CCB32';
            elseif strcmp(SelectedColor, 'Red')
                app.SnakeColorValue = [1, 0, 0]
            elseif strcmp(SelectedColor, 'Magenta')
                app.SnakeColorValue = [1, 0, 1]
            elseif strcmp(SelectedColor, 'Blue')
                app.SnakeColorValue = [0, 0, 1]
            end
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            % creating snake & background %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            dim = 20; % create the dimensions
            app.score = 0; % initialize original score to 0
            app.direction = 0; % initialize start to left direction
            cla(app.UIAxes) % clear graph

            s = 0.5; % define speed
            v = 0.2 % define time between iterations
            f=[0 0]; % define food vector for two positions
            f(1) = round(rand*dim); % define x dim random apparation of food
            f(2) = round(rand*dim); % define y dim random apparation of food

            s=zeros (100,2); % initialize & define snake vector
            s(1,1) = round(dim/2); % x dim snake head
            s(1,2) = round(dim/2); % y dim snake head
            s(2,1) = 5; % x dim snake body
            s(2,2) = 6; % y dim snake body

            % plot snake %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % define and plot the snake on the UI Axis for the different coordinates
            snake = plot(app.UIAxes, s([1 2],1), s([1 2], 2), 'Marker', ".","MarkerSize", 40, 'LineStyle',"-", "LineWidth",15, 'Color', app.SnakeColorValue);
            hold (app.UIAxes, 'on');

            % similar to the snake above, define the components of the food
            food = plot(app.UIAxes, f (1,1), f (1,2), 'Marker', ".", "MarkerSize", 25, 'MarkerFaceColor', 'r');
            xlim(app.UIAxes, [0,20]) % define the limit of the x axis
            ylim(app.UIAxes, [0,20]) % define the limit of the y axis
            while(1)
                id = 0;
                old_s = s; % assign the variable old_s to the previous iteration
                if (app.direction == 0) % if the direction is to the left
                    s(1,1) = old_s(1,1)-1; % x component of the snake turns
                    s(1,2) = old_s(1,2); % y component of the snake
                elseif(app.direction == 1) % if the direction is up
                    s(1,1) = old_s(1,1); % x component of the snake
                    s(1,2) = old_s(1,2)+1; % y component of the snake turns
                elseif(app.direction == 2) % if the direction is to the right
                    s(1,1) = old_s(1,1)+1; % x component of the snake turns
                    s(1,2) = old_s(1,2); % y component of the snake
                elseif(app.direction == 3) %if the direction is down
                    s(1,1) = old_s(1,1); % x component of the snake
                    s(1,2) = old_s(1,2)-1; % y component of the snake turns
                end

               % change the position of the snake's body
                for ii = 2:1:length(nonzeros(s(:,1))) % change the snake's body position starting with the second unit of the body to the end length
                    s(ii,1) = old_s (ii-1,1); % change old_s to new variable s for dim1
                    s(ii,2) = old_s (ii-1,2); % change old_s to new variable s for dim2
                end

                % end game if snake touches the wall %%%%%%%%%%%%%%%%%
                if (s(1,1)> 19.5 || s(1,1)< 0 || s(1,2)> 19.5|| s(1,2)< 0)
                    msgbox(strcat('Your snake has traveled out of bounds. Game over! Score: ', int2str(app.score)), 'End') % put together a string to display a message to the player that the game has ended
                    break
                end

                % end game if the snake touches itself
                if (isempty(find(prod(s(1,1:2)==s(2:end,1:2),2)))==0)
                    msgbox (strcat('Your snake has hit itself. Game over! Score: ', int2str(app.score)), 'End') % put together a string to display a message to the player that the game has ended
                    break
                end

                % eating points %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if (s(1,[1,2]) == f) %if the snake head touches food
                    s(length(nonzeros(s(:,1)))+1,1) = old_s(length(nonzeros(old_s(:,1))),1); % increase the length of the snake in dim 1
                    s(length(nonzeros(s(:,2)))+1,2) = old_s(length(nonzeros(old_s(:,2))),2); % increase the length of the snake in dim 2
                    f(1) = round(rand*dim); % spawn new food randomly in dim 1
                    f(2) = round(rand*dim); % spawn new food randomly in dim 2
                    app.score = app.score + 1; % update the app score
                    v = v - 0.02; % decrease the delay between iterations
                end
                
                %% plotting %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.SCOREEditField_2.Value = double(app.score);
                    %% convert the score to a string and update score in score box %%
                set(snake, 'XData', nonzeros(s(:,1)), 'YData', nonzeros(s(:,2)));
                    %% update the snakes x and y coordinates %%
                set(food, 'XData', nonzeros(f(:,1)), 'YData', nonzeros(f(:,2)));
                    %% update the foods x and y coordinates %%
                pause(v)
                    %% pauses the execution of the code for v seconds until key is pressed %%
          
            end
            

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
                    %% assign pressing of the key to variable key %%
                
                if (strcmp(key,'leftarrow') && app.direction ~= 2)
                    %% if the left arrow key is pressed and the snake isn't currently heading in the right direction ... %%
                    app.direction = 0; %% ... then the snake will change directions to the left %%
                end
                if (strcmp(key,'rightarrow') && app.direction ~= 0)
                    %% if the right arrow key is pressed and the snake isn't currently heading in the left direction ... %%
                    app.direction = 2; %% ... then the snake will change directions to the right %%
                end
                if (strcmp(key,'uparrow') && app.direction ~= 3)
                    %% if the up arrow key is pressed and the snake isn't currently heading in the down direction ... %%
                    app.direction = 1; %% ... then the snake will change directions to up %%
                end
                if (strcmp(key,'downarrow') && app.direction ~= 1)
                    %% if the down arrow key is pressed and the snake isn't currently heading in the up direction ... %%
                    app.direction = 3; %% ... then the snake will change directions to down %%
                end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.AvoidhittingthewallsoryourselforthegamewillendLabel = uilabel(app.UIFigure);
            app.AvoidhittingthewallsoryourselforthegamewillendLabel.Text = '4. Avoid hitting the walls or yourself, or the game will end!';
            app.EatthefoodtogrowlongerandincreaseyourscoreLabel = uilabel(app.UIFigure);
            app.EatthefoodtogrowlongerandincreaseyourscoreLabel.Text = '3. Eat the food to grow longer and increase your score. The longer the snake gets, the faster it will move!';
            app.SnakeLabel = uilabel(app.UIFigure);
            app.SnakeLabel.Text = 'Snake';
            app.UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel = uilabel(app.UIFigure);
            app.UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel.Text = '2. Use the arrow keys to control the snake''s movement (right, left, up, down).';
            app.PriyankaNambiarLabel = uilabel(app.UIFigure);
            app.PriyankaNambiarLabel.Text = 'Priyanka Nambiar';
            app.ShreyaGulatiLabel = uilabel(app.UIFigure);
            app.ShreyaGulatiLabel.Text = 'Shreya Gulati';
            app.NashGloverLabel = uilabel(app.UIFigure);
            app.NashGloverLabel.Text = 'Nash Glover';
            app.SCOREEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SCOREEditField_2Label = uilabel(app.UIFigure);
            app.SCOREEditField_2Label.Text = 'SCORE';
            app.SNAKECOLORDropDown = uidropdown(app.UIFigure);
            app.SNAKECOLORDropDown.Items = {'Choose a Color', 'Green', 'Blue', 'Red', 'Magenta'};
            app.SNAKECOLORDropDown.ValueChangedFcn = createCallbackFcn(app, @SNAKECOLORDropDownValueChanged, true);
            app.SNAKECOLORDropDown.Value = 'Choose a Color';
            app.SNAKECOLORDropDownLabel = uilabel(app.UIFigure);
            app.SNAKECOLORDropDownLabel.Text = 'SNAKE COLOR';
            app.UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel_2 = uilabel(app.UIFigure);
            app.UsethearrowkeystocontrolthesnakesmovementrightleftupdownLabel_2.Text = '1. Select what color you want your snake to be. Even if you want the default green, please select it.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnakeAppDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end